classdef Version2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        entradaYC               matlab.ui.control.NumericEditField
        EjeYEditField_4Label    matlab.ui.control.Label
        entradaXC               matlab.ui.control.NumericEditField
        EjeXEditField_4Label    matlab.ui.control.Label
        entradaYB               matlab.ui.control.NumericEditField
        EjeYEditField_3Label    matlab.ui.control.Label
        entradaXB               matlab.ui.control.NumericEditField
        EjeXEditField_3Label    matlab.ui.control.Label
        PuntoC                  matlab.ui.control.Label
        PuntoB                  matlab.ui.control.Label
        entradaAnguloL          matlab.ui.control.NumericEditField
        AnguloEditField_2Label  matlab.ui.control.Label
        EjeYEditField_2Label    matlab.ui.control.Label
        entradaYL               matlab.ui.control.NumericEditField
        EjeXEditField_2Label    matlab.ui.control.Label
        entradaXL               matlab.ui.control.NumericEditField
        AnguloEditFieldLabel    matlab.ui.control.Label
        Local                   matlab.ui.control.Label
        EjeYEditFieldLabel      matlab.ui.control.Label
        entradaAnguloG          matlab.ui.control.NumericEditField
        entradaYG               matlab.ui.control.NumericEditField
        entradaXG               matlab.ui.control.NumericEditField
        EjeXEditFieldLabel      matlab.ui.control.Label
        Global                  matlab.ui.control.Label
        ValoresaIngresar        matlab.ui.control.Label
        IngresarButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bt % Objeto Bluetooth
        timer % Objeto Timer
        posicionActual % [X Y Theta]
    end

    methods (Access = private)

        % Funciones auxiliares
        function plotRobot(app, xL, yL, thetaL, puntoB, puntoC)
            % Limpiar ejes
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')

            % Dibujar robot
            plot(app.UIAxes, app.posicionActual(1), app.posicionActual(2), 'ko', 'MarkerSize', 10, 'LineWidth', 2)

            % Dibujar flecha de dirección
            longitud_flecha = 1; % Puedes ajustar la longitud
            u = longitud_flecha * cosd(app.posicionActual(3));
            v = longitud_flecha * sind(app.posicionActual(3));
            quiver(app.UIAxes, app.posicionActual(1), app.posicionActual(2), u, v, 0, 'k', 'LineWidth', 1.5, 'MaxHeadSize', 2)

            % Dibujar puntos B y C en global
            theta_rad = deg2rad(thetaL);
            R = [cos(theta_rad), -sin(theta_rad); sin(theta_rad), cos(theta_rad)];

            puntoB_global = R * puntoB' + [xL; yL];
            puntoC_global = R * puntoC' + [xL; yL];

            plot(app.UIAxes, puntoB_global(1), puntoB_global(2), 'r*', 'MarkerSize', 10)
            plot(app.UIAxes, puntoC_global(1), puntoC_global(2), 'b*', 'MarkerSize', 10)

            % Opcional: dibujar líneas entre puntos
            plot(app.UIAxes, [app.posicionActual(1), puntoB_global(1), puntoC_global(1)], [app.posicionActual(2), puntoB_global(2), puntoC_global(2)], 'k--')

            % Ajustar ejes
            axis(app.UIAxes, 'equal')
            xlim(app.UIAxes, [app.posicionActual(1)-5 app.posicionActual(1)+5])
            ylim(app.UIAxes, [app.posicionActual(2)-5 app.posicionActual(2)+5])
            grid(app.UIAxes, 'on')

            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            title(app.UIAxes, 'Posición Robot en el sistema global')

            hold(app.UIAxes, 'off')
        end

        function actualizarRobot(app)
            try
                if app.bt.NumBytesAvailable > 0
                    datos = readline(app.bt); % Se espera "PX:12.346|PY:67.890|V:3.457|A:3.457|R1500.123"
                    px = str2double(extractBetween(datos, "PX:", "|PY"));
                    py = str2double(extractBetween(datos, "PY:", "|V"));
                    v  = str2double(extractBetween(datos, "V:", "|A"));
                    a  = str2double(extractBetween(datos, "A:", "|R"));
                    r  = str2double(extractAfter(datos, "R:"));  % hasta el final

                    disp([px, py, v, a, r]);

                    % Actualizar la posición actual
                    app.posicionActual = [px, py, a];
                end
            catch ME
                disp(['Error al actualizar: ' ME.message]);
            end
        end

        function enviarDatos(app, x, y, theta, xL, yL, thetaL, puntoB, puntoC)
            datos = "GEX:"+ x +"|GEY:"+ y +"|GEA:"+ theta +";LEX:"+ xL +"|LEY:"+ yL +"|LEA:"+ thetaL +";BX:"+ puntoB(1) +"|BY:"+ puntoB(2) +";CX:"+ puntoC(2) +"|CY:"+ puntoC(2) +";";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IngresarButton
        function IngresarButtonPushed(app, event)
            % Leer datos iniciales
            x = app.entradaXG.Value;
            y = app.entradaYG.Value;
            theta = app.entradaAnguloG.Value;

            xL = app.entradaXL.Value;
            yL = app.entradaYL.Value;
            thetaL = app.entradaAnguloL.Value;

            puntoB = [app.entradaXB.Value, app.entradaYB.Value];
            puntoC = [app.entradaXC.Value, app.entradaYC.Value];

            app.posicionActual = [x, y, theta];
            
            % Enviar Datos al arduino
            enviarDatos(app, x, y, theta, xL, yL, thetaL, puntoB, puntoC)

            % Graficar la posición inicial
            plotRobot(app, xL, yL, thetaL, puntoB, puntoC)

            % Crear y arrancar timer para actualizar automáticamente
            if isempty(app.timer) || ~isvalid(app.timer)
                app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 2, 'TimerFcn', @(~,~) actualizarRobot(app), plotRobot(app, xL, yL, thetaL, puntoB, puntoC));
                start(app.timer)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Posición Robot en el sistema global')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.ZLim = [0 10];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.IngresarButton = uibutton(app.GridLayout, 'push');
            app.IngresarButton.ButtonPushedFcn = createCallbackFcn(app, @IngresarButtonPushed, true);
            app.IngresarButton.Text = 'Ingresar';
            app.ValoresaIngresar = uilabel(app.GridLayout);
            app.ValoresaIngresar.Text = 'Valores a Ingresar';
            app.Global = uilabel(app.GridLayout);
            app.Global.Text = 'Posición en el sistema global';
            app.EjeXEditFieldLabel = uilabel(app.GridLayout);
            app.EjeXEditFieldLabel.Text = 'Eje X';
            app.entradaXG = uieditfield(app.GridLayout, 'numeric');
            app.entradaYG = uieditfield(app.GridLayout, 'numeric');
            app.entradaAnguloG = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditFieldLabel = uilabel(app.GridLayout);
            app.EjeYEditFieldLabel.Text = 'Eje Y';
            app.Local = uilabel(app.GridLayout);
            app.Local.Text = 'Posición del sistema local';
            app.AnguloEditFieldLabel = uilabel(app.GridLayout);
            app.AnguloEditFieldLabel.Text = 'Angulo (°)';
            app.entradaXL = uieditfield(app.GridLayout, 'numeric');
            app.EjeXEditField_2Label = uilabel(app.GridLayout);
            app.EjeXEditField_2Label.Text = 'Eje X';
            app.entradaYL = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditField_2Label = uilabel(app.GridLayout);
            app.EjeYEditField_2Label.Text = 'Eje Y';
            app.AnguloEditField_2Label = uilabel(app.GridLayout);
            app.AnguloEditField_2Label.Text = 'Angulo (°)';
            app.entradaAnguloL = uieditfield(app.GridLayout, 'numeric');
            app.PuntoB = uilabel(app.GridLayout);
            app.PuntoB.Text = 'Punto B';
            app.PuntoC = uilabel(app.GridLayout);
            app.PuntoC.Text = 'Punto C';
            app.EjeXEditField_3Label = uilabel(app.GridLayout);
            app.EjeXEditField_3Label.Text = 'Eje X';
            app.entradaXB = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditField_3Label = uilabel(app.GridLayout);
            app.EjeYEditField_3Label.Text = 'Eje Y';
            app.entradaYB = uieditfield(app.GridLayout, 'numeric');
            app.EjeXEditField_4Label = uilabel(app.GridLayout);
            app.EjeXEditField_4Label.Text = 'Eje X';
            app.entradaXC = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditField_4Label = uilabel(app.GridLayout);
            app.EjeYEditField_4Label.Text = 'Eje Y';
            app.entradaYC = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Version2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end