classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        entradaYC               matlab.ui.control.NumericEditField
        EjeYEditField_4Label    matlab.ui.control.Label
        entradaXC               matlab.ui.control.NumericEditField
        EjeXEditField_4Label    matlab.ui.control.Label
        entradaYB               matlab.ui.control.NumericEditField
        EjeYEditField_3Label    matlab.ui.control.Label
        entradaXB               matlab.ui.control.NumericEditField
        EjeXEditField_3Label    matlab.ui.control.Label
        PuntoC                  matlab.ui.control.Label
        PuntoB                  matlab.ui.control.Label
        entradaAnguloL          matlab.ui.control.NumericEditField
        AnguloEditField_2Label  matlab.ui.control.Label
        EjeYEditField_2Label    matlab.ui.control.Label
        entradaYL               matlab.ui.control.NumericEditField
        EjeXEditField_2Label    matlab.ui.control.Label
        entradaXL               matlab.ui.control.NumericEditField
        AnguloEditFieldLabel    matlab.ui.control.Label
        Local                   matlab.ui.control.Label
        EjeYEditFieldLabel      matlab.ui.control.Label
        entradaAnguloG          matlab.ui.control.NumericEditField
        entradaYG               matlab.ui.control.NumericEditField
        entradaXG               matlab.ui.control.NumericEditField
        EjeXEditFieldLabel      matlab.ui.control.Label
        Global                  matlab.ui.control.Label
        ValoresaIngresar        matlab.ui.control.Label
        INGRESARButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INGRESARButton
        function INGRESARButtonPushed(app, event)
            % Obtener valores del sistema global
            globalX = app.entradaXG.Value;
            globalY = app.entradaYG.Value;
            globalTheta = app.entradaAnguloG.Value;

            % Obtener valores del sistema local (aunque no se usan en el gráfico)
            localX = app.entradaXL.Value;
            localY = app.entradaYL.Value;
            localTheta = app.entradaAnguloL.Value;

            % Obtener punto B
            puntoB_X = app.entradaXB.Value;
            puntoB_Y = app.entradaYB.Value;

            % Obtener punto C
            puntoC_X = app.entradaXC.Value;
            puntoC_Y = app.entradaYC.Value;

            % Almacenar en vectores
            posicionGlobal = [globalX, globalY, globalTheta];
            sistemaLocal = [localX, localY, localTheta];
            puntoB_local = [puntoB_X, puntoB_Y];
            puntoC_local = [puntoC_X, puntoC_Y];

            %Aqui Comunicacion
            

            % Transformar puntos B y C del sistema local al global
            theta_rad = deg2rad(globalTheta); % Ángulo en radianes

            % Matriz de rotación
            R = [cos(theta_rad), -sin(theta_rad);
                sin(theta_rad),  cos(theta_rad)];

            % Aplicar rotación y traslación
            puntoB_global = R * puntoB_local' + [globalX; globalY];
            puntoC_global = R * puntoC_local' + [globalX; globalY];

            % Limpiar el gráfico
            cla(app.UIAxes)

            % Dibujar el robot como un marcador
            plot(app.UIAxes, globalX, globalY, 'ko', 'MarkerSize', 10, 'LineWidth', 2)
            hold(app.UIAxes, 'on')

            % Dibujar la flecha de orientación
            flechaLongitud = 1; % Puedes ajustar la longitud si quieres
            quiver(app.UIAxes, globalX, globalY, ...
                flechaLongitud*cos(theta_rad), flechaLongitud*sin(theta_rad), ...
                'Color', 'k', 'LineWidth', 2, 'MaxHeadSize', 1)

            % Dibujar los puntos B y C
            plot(app.UIAxes, puntoB_global(1), puntoB_global(2), 'r*', 'MarkerSize', 10)
            plot(app.UIAxes, puntoC_global(1), puntoC_global(2), 'b*', 'MarkerSize', 10)

            % Dibujar trayecto: Robot → B → C
            plot(app.UIAxes, ...
                [globalX, puntoB_global(1), puntoC_global(1)], ...
                [globalY, puntoB_global(2), puntoC_global(2)], ...
                'k--', 'LineWidth', 1.5)

            % Etiquetas y ajustes del gráfico
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            title(app.UIAxes, 'Posición Robot en el sistema global')

            % Ajuste automático de los ejes
            todosX = [globalX, puntoB_global(1), puntoC_global(1)];
            todosY = [globalY, puntoB_global(2), puntoC_global(2)];

            % Valores mínimos y máximos
            minX = min(todosX);
            maxX = max(todosX);
            minY = min(todosY);
            maxY = max(todosY);

            % Rango en cada eje
            rangoX = maxX - minX;
            rangoY = maxY - minY;

            % Elegir el rango más grande
            rangoMax = max(rangoX, rangoY);

            % Evitar rango cero
            if rangoMax == 0
                rangoMax = 1;
            end

            % Agregar 20% de margen
            margen = 0.2 * rangoMax;
            rangoFinal = rangoMax + 2*margen;

            % Centro
            centroX = (minX + maxX) / 2;
            centroY = (minY + maxY) / 2;

            % Límites preliminares
            limX = [centroX - rangoFinal/2, centroX + rangoFinal/2];
            limY = [centroY - rangoFinal/2, centroY + rangoFinal/2];

            % Elegir el múltiplo de redondeo (5 si es pequeño, 10 si es grande)
            if rangoFinal < 20
                multiplo = 5;
            else
                multiplo = 10;
            end

            % Redondear límites
            limX = [floor(limX(1)/multiplo)*multiplo, ceil(limX(2)/multiplo)*multiplo];
            limY = [floor(limY(1)/multiplo)*multiplo, ceil(limY(2)/multiplo)*multiplo];

            % Aplicar límites
            xlim(app.UIAxes, limX);
            ylim(app.UIAxes, limY);

            % Hacer cuadrado y bonito
            axis(app.UIAxes, 'equal')
            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'off')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Posición Robot en el sistema global')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.ZLim = [0 10];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.INGRESARButton = uibutton(app.GridLayout, 'push');
            app.INGRESARButton.ButtonPushedFcn = createCallbackFcn(app, @INGRESARButtonPushed, true);
            app.INGRESARButton.Text = 'INGRESAR';
            app.ValoresaIngresar = uilabel(app.GridLayout);
            app.ValoresaIngresar.Text = 'Valores a Ingresar';
            app.Global = uilabel(app.GridLayout);
            app.Global.Text = 'Posición en el sistema global';
            app.EjeXEditFieldLabel = uilabel(app.GridLayout);
            app.EjeXEditFieldLabel.Text = 'Eje X';
            app.entradaXG = uieditfield(app.GridLayout, 'numeric');
            app.entradaYG = uieditfield(app.GridLayout, 'numeric');
            app.entradaAnguloG = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditFieldLabel = uilabel(app.GridLayout);
            app.EjeYEditFieldLabel.Text = 'Eje Y';
            app.Local = uilabel(app.GridLayout);
            app.Local.Text = 'Posición del sistema local';
            app.AnguloEditFieldLabel = uilabel(app.GridLayout);
            app.AnguloEditFieldLabel.Text = 'Angulo (°)';
            app.entradaXL = uieditfield(app.GridLayout, 'numeric');
            app.EjeXEditField_2Label = uilabel(app.GridLayout);
            app.EjeXEditField_2Label.Text = 'Eje X';
            app.entradaYL = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditField_2Label = uilabel(app.GridLayout);
            app.EjeYEditField_2Label.Text = 'Eje Y';
            app.AnguloEditField_2Label = uilabel(app.GridLayout);
            app.AnguloEditField_2Label.Text = 'Angulo (°)';
            app.entradaAnguloL = uieditfield(app.GridLayout, 'numeric');
            app.PuntoB = uilabel(app.GridLayout);
            app.PuntoB.Text = 'Punto B';
            app.PuntoC = uilabel(app.GridLayout);
            app.PuntoC.Text = 'Punto C';
            app.EjeXEditField_3Label = uilabel(app.GridLayout);
            app.EjeXEditField_3Label.Text = 'Eje X';
            app.entradaXB = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditField_3Label = uilabel(app.GridLayout);
            app.EjeYEditField_3Label.Text = 'Eje Y';
            app.entradaYB = uieditfield(app.GridLayout, 'numeric');
            app.EjeXEditField_4Label = uilabel(app.GridLayout);
            app.EjeXEditField_4Label.Text = 'Eje X';
            app.entradaXC = uieditfield(app.GridLayout, 'numeric');
            app.EjeYEditField_4Label = uilabel(app.GridLayout);
            app.EjeYEditField_4Label.Text = 'Eje Y';
            app.entradaYC = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end